classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        Label_2                 matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        EditField_4             matlab.ui.control.NumericEditField
        ftEditFieldLabel        matlab.ui.control.Label
        ftEditField             matlab.ui.control.NumericEditField
        ftEditField_3Label      matlab.ui.control.Label
        ftEditField_3           matlab.ui.control.NumericEditField
        ftEditField_4Label      matlab.ui.control.Label
        ftEditField_4           matlab.ui.control.NumericEditField
        ftEditField_5Label      matlab.ui.control.Label
        ftEditField_5           matlab.ui.control.NumericEditField
        ftEditField_6Label      matlab.ui.control.Label
        ftEditField_6           matlab.ui.control.NumericEditField
        ftEditField_2Label      matlab.ui.control.Label
        ftEditField_2           matlab.ui.control.NumericEditField
        Panel_2                 matlab.ui.container.Panel
        AntNumEditFieldLabel    matlab.ui.control.Label
        AntNumEditField         matlab.ui.control.NumericEditField
        alphaEditFieldLabel     matlab.ui.control.Label
        alphaEditField          matlab.ui.control.NumericEditField
        betaEditFieldLabel      matlab.ui.control.Label
        betaEditField           matlab.ui.control.NumericEditField
        rhoEditFieldLabel       matlab.ui.control.Label
        rhoEditField            matlab.ui.control.NumericEditField
        min_etaEditFieldLabel   matlab.ui.control.Label
        min_etaEditField        matlab.ui.control.NumericEditField
        Q_antEditFieldLabel     matlab.ui.control.Label
        Q_antEditField          matlab.ui.control.NumericEditField
        Tau_minEditFieldLabel   matlab.ui.control.Label
        Tau_minEditField        matlab.ui.control.NumericEditField
        Tau_maxEditFieldLabel   matlab.ui.control.Label
        Tau_maxEditField        matlab.ui.control.NumericEditField
        iter_maxEditFieldLabel  matlab.ui.control.Label
        iter_maxEditField       matlab.ui.control.NumericEditField
        Panel_3                 matlab.ui.container.Panel
        MMASButtonGroup         matlab.ui.container.ButtonGroup
        Button_2                matlab.ui.control.RadioButton
        Button_3                matlab.ui.control.RadioButton
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button_4                matlab.ui.control.RadioButton
        Button_5                matlab.ui.control.RadioButton
        ButtonGroup_2           matlab.ui.container.ButtonGroup
        Button_6                matlab.ui.control.RadioButton
        Button_7                matlab.ui.control.RadioButton
        Label                   matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        EditField_5             matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        Button_9                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
          value_cus = app.EditField_3.Value;
          assignin('base','Cus_size',value_cus);
          
          value_task = app.EditField_4.Value;
          assignin('base','Task_size',value_task);
          
          value_20ft = app.ftEditField.Value;
          assignin('base','Task_20ft_size',value_20ft);
          
          value_20ft_inbound = app.ftEditField_3.Value;
          assignin('base','Task_20ft_inbound_size',value_20ft_inbound);
          
          value_20ft_outbound = app.ftEditField_4.Value;
          assignin('base','Task_20ft_outbound_size',value_20ft_outbound); 
          
          value_40ft = app.ftEditField_2.Value;
          assignin('base','Task_40ft_size',value_40ft);  
            
          value_40ft_inbound = app.ftEditField_5.Value;
          assignin('base','Task_40ft_inbound_size',value_40ft_inbound);
          
          value_40ft_outbound = app.ftEditField_6.Value;
          assignin('base','Task_40ft_outbound_size',value_40ft_outbound);            
            
          assignin('base','AntNum',app.AntNumEditField.Value)
          assignin('base','alpha',app.alphaEditField.Value)
          assignin('base','beta',app.betaEditField.Value)
          assignin('base','rho',app.rhoEditField.Value)
          assignin('base','min_eta',app.min_etaEditField.Value)
          assignin('base','Q_ant',app.Q_antEditField.Value)
          assignin('base','Tau_min',app.Tau_minEditField.Value)
          assignin('base','Tau_max',app.Tau_maxEditField.Value)
          assignin('base','iter_max',app.iter_maxEditField.Value)
          assignin('base','Run_test_Num',app.EditField_5.Value)
          assignin('base','MMAS',app.Button_2.Value)
          assignin('base','Opt_Vei_Num',app.Button_4.Value)
          assignin('base','Stop_iter',app.Button_6.Value)
          assignin('base','Stop_iter_Num',app.EditField_2.Value)
          assignin('base','VeiNum',app.EditField_6.Value)


        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            if value >0
                app.Button_8.Enable = 'on';
            end


        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case '是'
                    app.EditField_2.Enable = 'on';
                case '否'
                    app.EditField_2.Enable = 'off';
            end     
        end

        % Size changed function: ButtonGroup_2
        function ButtonGroup_2SizeChanged(app, event)
            position = app.ButtonGroup_2.Position;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value_cus = app.EditField_3.Value;% 客户点数量
            app.EditField_4.Limits = [value_cus,Inf];
        end

        % Value changed function: ftEditField
        function ftEditFieldValueChanged(app, event)
            value_task = app.EditField_4.Value;  %总任务数量
            app.ftEditField.Limits = [0,value_task];
            value_20ft = app.ftEditField.Value;% 20ft任务数量
            app.ftEditField_2.Value = value_task - value_20ft;%40ft任务数量
            
        end

        % Value changed function: ftEditField_2
        function ftEditField_2ValueChanged(app, event)


        end

        % Value changed function: ftEditField_3
        function ftEditField_3ValueChanged(app, event)
            value_20ft = app.ftEditField.Value;% 20ft任务数量
            app.ftEditField_3.Limits = [0,value_20ft];
            value_20ft_inbound = app.ftEditField_3.Value;%20ft进港
            app.ftEditField_4.Value = value_20ft - value_20ft_inbound;
            
        end

        % Value changed function: ftEditField_5
        function ftEditField_5ValueChanged(app, event)
            value_40ft =  app.ftEditField_2.Value; %40ft任务数量
            app.ftEditField_5.Limits = [0,value_40ft];
            value_40ft_inbound = app.ftEditField_5.Value;
            app.ftEditField_6.Value = value_40ft - value_40ft_inbound;


        end

        % Callback function
        function Button_8Pushed(app, event)
           run multi_size_truck.m
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            clc
             evalin('base','clear')
           % run close all
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            VeiNum = app.EditField_6.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入算例';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '算例设置';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '客户点数量';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Value = 4;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '任务点数量';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 4;
            app.ftEditFieldLabel = uilabel(app.Panel);
            app.ftEditFieldLabel.Text = '20ft 任务';
            app.ftEditField = uieditfield(app.Panel, 'numeric');
            app.ftEditField.ValueChangedFcn = createCallbackFcn(app, @ftEditFieldValueChanged, true);
            app.ftEditField.Value = 2;
            app.ftEditField_3Label = uilabel(app.Panel);
            app.ftEditField_3Label.Text = '20ft 进港';
            app.ftEditField_3 = uieditfield(app.Panel, 'numeric');
            app.ftEditField_3.ValueChangedFcn = createCallbackFcn(app, @ftEditField_3ValueChanged, true);
            app.ftEditField_3.Value = 1;
            app.ftEditField_4Label = uilabel(app.Panel);
            app.ftEditField_4Label.Text = '20ft 出港';
            app.ftEditField_4 = uieditfield(app.Panel, 'numeric');
            app.ftEditField_4.Editable = 'off';
            app.ftEditField_4.Value = 1;
            app.ftEditField_5Label = uilabel(app.Panel);
            app.ftEditField_5Label.Text = '40ft 进港';
            app.ftEditField_5 = uieditfield(app.Panel, 'numeric');
            app.ftEditField_5.ValueChangedFcn = createCallbackFcn(app, @ftEditField_5ValueChanged, true);
            app.ftEditField_5.Value = 1;
            app.ftEditField_6Label = uilabel(app.Panel);
            app.ftEditField_6Label.Text = '40ft 出港';
            app.ftEditField_6 = uieditfield(app.Panel, 'numeric');
            app.ftEditField_6.Editable = 'off';
            app.ftEditField_6.Value = 1;
            app.ftEditField_2Label = uilabel(app.Panel);
            app.ftEditField_2Label.Text = '40ft 任务';
            app.ftEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ftEditField_2.ValueChangedFcn = createCallbackFcn(app, @ftEditField_2ValueChanged, true);
            app.ftEditField_2.Editable = 'off';
            app.ftEditField_2.Value = 2;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '算法参数设置';
            app.AntNumEditFieldLabel = uilabel(app.Panel_2);
            app.AntNumEditFieldLabel.Text = 'AntNum:';
            app.AntNumEditField = uieditfield(app.Panel_2, 'numeric');
            app.AntNumEditField.Value = 20;
            app.alphaEditFieldLabel = uilabel(app.Panel_2);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.Panel_2, 'numeric');
            app.alphaEditField.Value = 3;
            app.betaEditFieldLabel = uilabel(app.Panel_2);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.Panel_2, 'numeric');
            app.betaEditField.Value = 1;
            app.rhoEditFieldLabel = uilabel(app.Panel_2);
            app.rhoEditFieldLabel.Text = 'rho';
            app.rhoEditField = uieditfield(app.Panel_2, 'numeric');
            app.rhoEditField.Value = 0.1;
            app.min_etaEditFieldLabel = uilabel(app.Panel_2);
            app.min_etaEditFieldLabel.Text = 'min_eta';
            app.min_etaEditField = uieditfield(app.Panel_2, 'numeric');
            app.min_etaEditField.Value = 10;
            app.Q_antEditFieldLabel = uilabel(app.Panel_2);
            app.Q_antEditFieldLabel.Text = 'Q_ant';
            app.Q_antEditField = uieditfield(app.Panel_2, 'numeric');
            app.Q_antEditField.Value = 50;
            app.Tau_minEditFieldLabel = uilabel(app.Panel_2);
            app.Tau_minEditFieldLabel.Text = 'Tau_min';
            app.Tau_minEditField = uieditfield(app.Panel_2, 'numeric');
            app.Tau_minEditField.Value = 0.5;
            app.Tau_maxEditFieldLabel = uilabel(app.Panel_2);
            app.Tau_maxEditFieldLabel.Text = 'Tau_max';
            app.Tau_maxEditField = uieditfield(app.Panel_2, 'numeric');
            app.Tau_maxEditField.Value = 3;
            app.iter_maxEditFieldLabel = uilabel(app.Panel_2);
            app.iter_maxEditFieldLabel.Text = 'iter_max';
            app.iter_maxEditField = uieditfield(app.Panel_2, 'numeric');
            app.iter_maxEditField.Value = 500;
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '算法优化策略';
            app.MMASButtonGroup = uibuttongroup(app.Panel_3);
            app.MMASButtonGroup.Title = 'MMAS策略';
            app.Button_2 = uiradiobutton(app.MMASButtonGroup);
            app.Button_2.Text = '是';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.MMASButtonGroup);
            app.Button_3.Text = '否';
            app.ButtonGroup = uibuttongroup(app.Panel_3);
            app.ButtonGroup.Title = '最优车辆控制';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '是';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '否';
            app.Button_5.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.Panel_3);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '提前终止策略';
            app.ButtonGroup_2.SizeChangedFcn = createCallbackFcn(app, @ButtonGroup_2SizeChanged, true);
            app.Button_6 = uiradiobutton(app.ButtonGroup_2);
            app.Button_6.Text = '是';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Text = '否';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '终止代数';
            app.EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_2.Value = 100;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '运行次数';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 10;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '车辆数量';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 3;
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '初始化';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end